classdef App_ISEL_Traverse_manuell_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Toolbar                     matlab.ui.container.Toolbar
        PushTool                    matlab.ui.container.toolbar.PushTool
        YLabel_2                    matlab.ui.control.Label
        PanelMoveY                  matlab.ui.container.Panel
        PanelYRep                   matlab.ui.container.Panel
        Image_2                     matlab.ui.control.Image
        Switch_YRepeat              matlab.ui.control.Switch
        EditField_YDelay            matlab.ui.control.NumericEditField
        DelaysEditFieldLabel_2      matlab.ui.control.Label
        EditField_YRepeat           matlab.ui.control.NumericEditField
        RepetitionEditFieldLabel_2  matlab.ui.control.Label
        PanelYAbs                   matlab.ui.container.Panel
        EditField_YAbsSpeed         matlab.ui.control.NumericEditField
        SpeedEditField_3Label_2     matlab.ui.control.Label
        EditField_YPos              matlab.ui.control.NumericEditField
        PositionmmEditFieldLabel_2  matlab.ui.control.Label
        Button_YMoveAbs             matlab.ui.control.StateButton
        PanelYRel                   matlab.ui.container.Panel
        EditField_XRelSpeed_2       matlab.ui.control.NumericEditField
        SpeedEditField_2Label_2     matlab.ui.control.Label
        EditField_XDist_2           matlab.ui.control.NumericEditField
        DistancemmEditFieldLabel_2  matlab.ui.control.Label
        Button_XMoveRel_2           matlab.ui.control.StateButton
        Terminal                    matlab.ui.control.TextArea
        TerminalTextAreaLabel       matlab.ui.control.Label
        PanelSettings               matlab.ui.container.Panel
        EditField_Steps             matlab.ui.control.NumericEditField
        StepsEditFieldLabel         matlab.ui.control.Label
        EditField_Elev              matlab.ui.control.NumericEditField
        ElevationmmEditFieldLabel   matlab.ui.control.Label
        Image3                      matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
        XLabel                      matlab.ui.control.Label
        PanelMoveX                  matlab.ui.container.Panel
        PanelXRep                   matlab.ui.container.Panel
        Image                       matlab.ui.control.Image
        Switch_XRepeat              matlab.ui.control.Switch
        EditField_XDelay            matlab.ui.control.NumericEditField
        DelaysEditFieldLabel        matlab.ui.control.Label
        EditField_XRepeat           matlab.ui.control.NumericEditField
        RepetitionEditFieldLabel    matlab.ui.control.Label
        PanelXAbs                   matlab.ui.container.Panel
        EditField_XAbsSpeed         matlab.ui.control.NumericEditField
        SpeedEditField_3Label       matlab.ui.control.Label
        EditField_XPos              matlab.ui.control.NumericEditField
        PositionmmEditFieldLabel    matlab.ui.control.Label
        Button_XMoveAbs             matlab.ui.control.StateButton
        PanelXRel                   matlab.ui.container.Panel
        EditField_XRelSpeed         matlab.ui.control.NumericEditField
        SpeedEditField_2Label       matlab.ui.control.Label
        EditField_XDist             matlab.ui.control.NumericEditField
        DistancemmEditFieldLabel    matlab.ui.control.Label
        Button_XMoveRel             matlab.ui.control.StateButton
        PanelReference              matlab.ui.container.Panel
        EditField_RefSpeed          matlab.ui.control.NumericEditField
        SpeedEditFieldLabel         matlab.ui.control.Label
        Button_RefXY                matlab.ui.control.StateButton
        Button_RefY                 matlab.ui.control.StateButton
        Button_RefX                 matlab.ui.control.StateButton
        PanelAxis                   matlab.ui.container.Panel
        Button_AxisXY               matlab.ui.control.StateButton
        Button_AxisY                matlab.ui.control.StateButton
        Button_AxisX                matlab.ui.control.StateButton
        PanelConnection             matlab.ui.container.Panel
        EditField_Baudrate          matlab.ui.control.NumericEditField
        BaudrateEditFieldLabel      matlab.ui.control.Label
        EditField_COM               matlab.ui.control.NumericEditField
        COMPortEditFieldLabel       matlab.ui.control.Label
        LampConnection              matlab.ui.control.Lamp
        SwitchConnection            matlab.ui.control.Switch
    end


    methods (Access = private)

        function funcRef(app, buttonRef, refSpeed)
            if buttonRef == 1
                try
                    t_pause = 0.1;
                    writeline(device,"@01");                  % 1 Achsen aktivieren #todo 2 achsen code?
                    pause(t_pause);
                    writeline(device,['@0d',num2str(refSpeed)]);    % Ref speed setzen
                    pause(t_pause);
                    writeline(device,"@0i");                  % #input Befehl
                    pause(t_pause);
                    writeline(device,"71");                   % X Achse referenzieren
                    pause(t_pause);
                    writeline(device,"9");                    % stop. Befehl
                    pause(t_pause);
                    writeline(device,"@0S");                  % #start Befehl
                    buttonRef = 0;
                    app.Terminal.Value = [app.Terminal.Value,...
                        ['Reference ',axisStr]];
                catch ME
                    app.Terminal.Value = [app.Terminal.Value,...
                        ME.message];

                end
            end
        end

        function funcMoveRel(app,buttonMoveRel, elev, steps, axisSpeed)
            if buttonMoveRel == 1
                try
                    t_pause = 0.1;
                    writeline(device,"@01");                  % 1 Achsen aktivieren #todo 2 achsen code?
                    pause(t_pause);
                    writeline(device,"@0i");                  % #input Befehl
                    pause(t_pause);
                    writeline(device,['03',num2str(elev*steps/100*dist),...
                        ',',num2str(axisSpeed),'0,5000']);        % move relative 100m (steps 800, elev 2.5mm)
                    pause(t_pause);
                    writeline(device,"9");                    % stop. Befehl
                    pause(t_pause);
                    writeline(device,"@0S");                  % #start Befehl
                    buttonMoveRel = 0;
                    app.Terminal.Value = [app.Terminal.Value,...
                        ['Reference ',axisStr]];
                catch ME
                    app.Terminal.Value = [app.Terminal.Value,...
                        ME.message];

                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SwitchConnection
        function SwitchConnectionValueChanged(app, event)
            value = app.SwitchConnection.Value;

            if value == "On"
                try
                    device = serialport(['COM',num2str(app.EditField_COM.Value)],app.EditField_Baudrate.Value);
                    app.Terminal.Value = [app.Terminal.Value,...
                        'Connected'];
                    app.LampConnection.Color = [0.39,0.83,0.07];
                    configureTerminator(device,"CR/LF")      % unbedingt notwendig, für ECHO Antwort
                    pause(0.1);
                    device.Timeout = 10; % default 10
                    pause(0.1);
                    app.PanelAxis.Enable = 1;
                    app.PanelSettings.Enable = 1;
                catch ME
                    app.Terminal.Value = [app.Terminal.Value,...
                        ME.message];
                end
            else
                % close serial port
                delete(device);
                app.LampConnection.Color = [1,0,0];
                app.Terminal.Value = [app.Terminal.Value,...
                    'Disconnected'];
            end

        end

        % Value changed function: Button_AxisXY
        function Button_AxisXYValueChanged(app, event)
            value = app.Button_AxisXY.Value;

            if value == 1
                app.PanelMoveX.Enable = 1;
                app.PanelMoveY.Enable = 1;
                app.Button_RefX.Enable = 1;
                app.Button_RefY.Enable = 1;
                app.Button_RefXY.Enable = 1;
            else
                app.PanelMoveX.Enable = 0;
                app.PanelMoveY.Enable = 0;
                app.Button_AxisX.Enable = 0;
                app.Button_RefY.Enable = 0;
            end
        end

        % Value changed function: Button_AxisY
        function Button_AxisYValueChanged(app, event)
            value = app.Button_AxisY.Value;

            if value == 1
                app.PanelMoveY.Enable = 1;
                app.Button_RefY.Enable = 1;
            else
                app.PanelMoveY.Enable = 0;
                app.Button_RefY.Enable = 0;
            end
        end

        % Value changed function: Button_AxisX
        function Button_AxisXValueChanged(app, event)
            value = app.Button_AxisX.Value;

            if value == 1
                app.PanelMoveX.Enable = 1;
                app.Button_RefX.Enable = 1;
            else
                app.PanelMoveX.Enable = 0;
                app.Button_RefX.Enable = 0;
            end
        end

        % Value changed function: Button_RefX
        function Button_RefXValueChanged(app, event)
            axisStr = 'X'

            funcRef(app,app.Button_RefXValueChanged.Value, app.EditField_RefSpeed.Value)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PanelConnection = uipanel(app.UIFigure);
            app.PanelConnection.Title = '1. Connection';
            app.SwitchConnection = uiswitch(app.PanelConnection, 'slider');
            app.SwitchConnection.ValueChangedFcn = createCallbackFcn(app, @SwitchConnectionValueChanged, true);
            app.LampConnection = uilamp(app.PanelConnection);
            app.COMPortEditFieldLabel = uilabel(app.PanelConnection);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.EditField_COM = uieditfield(app.PanelConnection, 'numeric');
            app.EditField_COM.Value = 1;
            app.BaudrateEditFieldLabel = uilabel(app.PanelConnection);
            app.BaudrateEditFieldLabel.Text = 'Baudrate';
            app.EditField_Baudrate = uieditfield(app.PanelConnection, 'numeric');
            app.EditField_Baudrate.ValueDisplayFormat = '%11.0f';
            app.EditField_Baudrate.Editable = 'off';
            app.EditField_Baudrate.Value = 19200;
            app.PanelAxis = uipanel(app.UIFigure);
            app.PanelAxis.Enable = 'off';
            app.PanelAxis.Title = 'Axis';
            app.Button_AxisX = uibutton(app.PanelAxis, 'state');
            app.Button_AxisX.ValueChangedFcn = createCallbackFcn(app, @Button_AxisXValueChanged, true);
            app.Button_AxisX.Text = 'X';
            app.Button_AxisY = uibutton(app.PanelAxis, 'state');
            app.Button_AxisY.ValueChangedFcn = createCallbackFcn(app, @Button_AxisYValueChanged, true);
            app.Button_AxisY.Text = 'Y';
            app.Button_AxisXY = uibutton(app.PanelAxis, 'state');
            app.Button_AxisXY.ValueChangedFcn = createCallbackFcn(app, @Button_AxisXYValueChanged, true);
            app.Button_AxisXY.Text = 'X, Y';
            app.PanelReference = uipanel(app.UIFigure);
            app.PanelReference.Title = 'Reference';
            app.Button_RefX = uibutton(app.PanelReference, 'state');
            app.Button_RefX.ValueChangedFcn = createCallbackFcn(app, @Button_RefXValueChanged, true);
            app.Button_RefX.Enable = 'off';
            app.Button_RefX.Text = 'X';
            app.Button_RefY = uibutton(app.PanelReference, 'state');
            app.Button_RefY.Enable = 'off';
            app.Button_RefY.Text = 'Y';
            app.Button_RefXY = uibutton(app.PanelReference, 'state');
            app.Button_RefXY.Enable = 'off';
            app.Button_RefXY.Text = 'X, Y';
            app.SpeedEditFieldLabel = uilabel(app.PanelReference);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.EditField_RefSpeed = uieditfield(app.PanelReference, 'numeric');
            app.EditField_RefSpeed.Value = 5000;
            app.PanelMoveX = uipanel(app.UIFigure);
            app.PanelMoveX.Enable = 'off';
            app.PanelMoveX.Title = 'Move X';
            app.PanelXRel = uipanel(app.PanelMoveX);
            app.PanelXRel.Title = 'Relative';
            app.Button_XMoveRel = uibutton(app.PanelXRel, 'state');
            app.Button_XMoveRel.Text = 'Move';
            app.DistancemmEditFieldLabel = uilabel(app.PanelXRel);
            app.DistancemmEditFieldLabel.Text = 'Distance [mm]';
            app.EditField_XDist = uieditfield(app.PanelXRel, 'numeric');
            app.SpeedEditField_2Label = uilabel(app.PanelXRel);
            app.SpeedEditField_2Label.Text = 'Speed';
            app.EditField_XRelSpeed = uieditfield(app.PanelXRel, 'numeric');
            app.EditField_XRelSpeed.Value = 5000;
            app.PanelXAbs = uipanel(app.PanelMoveX);
            app.PanelXAbs.Title = 'Absolute';
            app.Button_XMoveAbs = uibutton(app.PanelXAbs, 'state');
            app.Button_XMoveAbs.Text = 'Move';
            app.PositionmmEditFieldLabel = uilabel(app.PanelXAbs);
            app.PositionmmEditFieldLabel.Text = 'Position [mm]';
            app.EditField_XPos = uieditfield(app.PanelXAbs, 'numeric');
            app.SpeedEditField_3Label = uilabel(app.PanelXAbs);
            app.SpeedEditField_3Label.Text = 'Speed';
            app.EditField_XAbsSpeed = uieditfield(app.PanelXAbs, 'numeric');
            app.EditField_XAbsSpeed.Value = 5000;
            app.PanelXRep = uipanel(app.PanelMoveX);
            app.PanelXRep.Title = 'Relative Repeat';
            app.RepetitionEditFieldLabel = uilabel(app.PanelXRep);
            app.RepetitionEditFieldLabel.Text = 'Repetition';
            app.EditField_XRepeat = uieditfield(app.PanelXRep, 'numeric');
            app.EditField_XRepeat.Value = 1;
            app.DelaysEditFieldLabel = uilabel(app.PanelXRep);
            app.DelaysEditFieldLabel.Text = 'Delay [s]';
            app.EditField_XDelay = uieditfield(app.PanelXRep, 'numeric');
            app.EditField_XDelay.Value = 2;
            app.Switch_XRepeat = uiswitch(app.PanelXRep, 'slider');
            app.Image = uiimage(app.PanelXRep);
            app.Image.ImageSource = 'symbolRepeat.png';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'wawi-logo.png';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'isel-logo.png';
            app.PanelSettings = uipanel(app.UIFigure);
            app.PanelSettings.Title = 'Settings';
            app.ElevationmmEditFieldLabel = uilabel(app.PanelSettings);
            app.ElevationmmEditFieldLabel.Text = 'Elevation [mm]';
            app.EditField_Elev = uieditfield(app.PanelSettings, 'numeric');
            app.EditField_Elev.Editable = 'off';
            app.EditField_Elev.Value = 2.5;
            app.StepsEditFieldLabel = uilabel(app.PanelSettings);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.EditField_Steps = uieditfield(app.PanelSettings, 'numeric');
            app.EditField_Steps.Editable = 'off';
            app.EditField_Steps.Value = 800;
            app.TerminalTextAreaLabel = uilabel(app.UIFigure);
            app.TerminalTextAreaLabel.Text = 'Terminal';
            app.Terminal = uitextarea(app.UIFigure);
            app.Terminal.Editable = 'off';
            app.Terminal.Value = {'Please switch on...'};
            app.PanelMoveY = uipanel(app.UIFigure);
            app.PanelMoveY.Enable = 'off';
            app.PanelMoveY.Title = 'Move X';
            app.PanelYRel = uipanel(app.PanelMoveY);
            app.PanelYRel.Title = 'Relative';
            app.Button_XMoveRel_2 = uibutton(app.PanelYRel, 'state');
            app.Button_XMoveRel_2.Text = 'Move';
            app.DistancemmEditFieldLabel_2 = uilabel(app.PanelYRel);
            app.DistancemmEditFieldLabel_2.Text = 'Distance [mm]';
            app.EditField_XDist_2 = uieditfield(app.PanelYRel, 'numeric');
            app.SpeedEditField_2Label_2 = uilabel(app.PanelYRel);
            app.SpeedEditField_2Label_2.Text = 'Speed';
            app.EditField_XRelSpeed_2 = uieditfield(app.PanelYRel, 'numeric');
            app.EditField_XRelSpeed_2.Value = 5000;
            app.PanelYAbs = uipanel(app.PanelMoveY);
            app.PanelYAbs.Title = 'Absolute';
            app.Button_YMoveAbs = uibutton(app.PanelYAbs, 'state');
            app.Button_YMoveAbs.Text = 'Move';
            app.PositionmmEditFieldLabel_2 = uilabel(app.PanelYAbs);
            app.PositionmmEditFieldLabel_2.Text = 'Position [mm]';
            app.EditField_YPos = uieditfield(app.PanelYAbs, 'numeric');
            app.SpeedEditField_3Label_2 = uilabel(app.PanelYAbs);
            app.SpeedEditField_3Label_2.Text = 'Speed';
            app.EditField_YAbsSpeed = uieditfield(app.PanelYAbs, 'numeric');
            app.EditField_YAbsSpeed.Value = 5000;
            app.PanelYRep = uipanel(app.PanelMoveY);
            app.PanelYRep.Title = 'Relative Repeat';
            app.RepetitionEditFieldLabel_2 = uilabel(app.PanelYRep);
            app.RepetitionEditFieldLabel_2.Text = 'Repetition';
            app.EditField_YRepeat = uieditfield(app.PanelYRep, 'numeric');
            app.EditField_YRepeat.Value = 1;
            app.DelaysEditFieldLabel_2 = uilabel(app.PanelYRep);
            app.DelaysEditFieldLabel_2.Text = 'Delay [s]';
            app.EditField_YDelay = uieditfield(app.PanelYRep, 'numeric');
            app.EditField_YDelay.Value = 2;
            app.Switch_YRepeat = uiswitch(app.PanelYRep, 'slider');
            app.Image_2 = uiimage(app.PanelYRep);
            app.Image_2.ImageSource = 'symbolRepeat.png';
            app.YLabel_2 = uilabel(app.UIFigure);
            app.YLabel_2.Text = 'Y';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_ISEL_Traverse_manuell_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end